classdef Crystal2MULTEM_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SampleSpecificationsTab       matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        Crystal2MULTEMLabel           matlab.ui.control.Label
        ErrorCodesEditField           matlab.ui.control.EditField
        ErrorCodesLabel               matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        FileNameEditField             matlab.ui.control.EditField
        FileNameLabel                 matlab.ui.control.Label
        FileButton                    matlab.ui.control.Button
        Panel_2                       matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        SaveFileEditField             matlab.ui.control.EditField
        SaveFileEditFieldLabel        matlab.ui.control.Label
        SaveFileButton                matlab.ui.control.Button
        CONVERTButton                 matlab.ui.control.Button
        CLEARButton                   matlab.ui.control.Button
        ParametersPanel               matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        SampleXDimensionAngEditField  matlab.ui.control.NumericEditField
        SampleXDimensionAngEditFieldLabel  matlab.ui.control.Label
        SampleYDimensionAngEditField  matlab.ui.control.NumericEditField
        SampleYDimensionAngEditFieldLabel  matlab.ui.control.Label
        SliceThicknessAngEditField    matlab.ui.control.NumericEditField
        SliceThicknessAngEditFieldLabel  matlab.ui.control.Label
        RegionEditField               matlab.ui.control.NumericEditField
        RegionEditFieldLabel          matlab.ui.control.Label
        OccupancyEditField            matlab.ui.control.NumericEditField
        OccupancyLabel                matlab.ui.control.Label
        RootMeanSquaredDisplacementEditField  matlab.ui.control.NumericEditField
        RootMeanSquaredDisplacementLabel  matlab.ui.control.Label
        ChargeEditField               matlab.ui.control.NumericEditField
        ChargeEditFieldLabel          matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        PreviewTab                    matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
    end

    
    properties (Access = private)
        sizeX;
        sizeY;
        dz;
        rms;
        occupancy;
        charge;
        region;
        MultemFile;
        FileType;
        save;
        tabledata;
        NumAtoms;
        FileName;
        Output;
        numat;
        TimingData;
        Data;
        DifZ;
        TopLine;
        PreparedData;
        Translata;
        file;
    end
    
    methods (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
cla(app.UIAxes);
            app.UITable.Data=[];
app.UITable.ColumnName={'Atomic Number','X','Y','Z','RMS','Occupancy','Region','Charge'};
app.ChargeEditField.Value=0;
app.FileNameEditField.Value='';
app.OccupancyEditField.Value=1;
app.RegionEditField.Value=0;
app.RootMeanSquaredDisplacementEditField.Value=0.085;
app.SampleXDimensionAngEditField.Value=0;
app.SampleYDimensionAngEditField.Value=0;
app.SaveFileEditField.Value='';
app.SliceThicknessAngEditField.Value=0;
app.ErrorCodesEditField.Value='Error Codes Will Enter Here';
    app.UIAxes.Box='on';
    app.UIAxes.XTick=[];
    app.UIAxes.YTick=[];
    app.UIAxes.Color=[0.9375 0.9375 0.9375];
    app.UIAxes.XAxis.Limits=[0,1];
    app.UIAxes.YAxis.Limits=[0,1];
title(app.UIAxes,'Progress')
% Create empty patch that will be updated
ph = patch(app.UIAxes,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %#ok<NASGU> %greenyellow
% Create the percent-complete text that will be updated
th = text(app.UIAxes,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right'); %#ok<NASGU> 
        end

        % Button pushed function: FileButton
        function FileButtonPushed(app, event)
            try
                [fileA,path]=uigetfile('*.txt');
                app.file=append(path,fileA);
                [~,name,extension]=fileparts(app.file);
                app.FileNameEditField.Value=append(name,extension);
                if endsWith(extension,'.txt') && startsWith(extension,'.txt')
                    app.ErrorCodesEditField.Value='Corerct file type';
                    app.ErrorCodesEditField.BackgroundColor='g';
                else
                    app.ErrorCodesEditField.Value='Incorrect file type';
                    app.ErrorCodesEditField.BackgroundColor='r';
                end
                app.ErrorCodesEditField.Value='File loaded successfully';
                app.ErrorCodesEditField.BackgroundColor='g';
            catch
                app.ErrorCodesEditField.Value='File failed to load';
                app.ErrorCodesEditField.BackgroundColor='r';
            end
            
        end

        % Button pushed function: CONVERTButton
        function CONVERTButtonPushed(app, event)
app.UIAxes.Box='on';
app.UIAxes.XTick=[];
app.UIAxes.YTick=[];
app.UIAxes.Color=[0.9375 0.9375 0.9375];
app.UIAxes.XAxis.Limits=[0,1];
app.UIAxes.YAxis.Limits=[0,1];
title(app.UIAxes,'Progress')
ph = patch(app.UIAxes,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); 
th = text(app.UIAxes,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right'); 

app.save=0;
app.FileType=1;
app.sizeX=app.SampleXDimensionAngEditField.Value;
app.sizeY=app.SampleYDimensionAngEditField.Value;
app.dz=app.SliceThicknessAngEditField.Value;
app.rms=app.RootMeanSquaredDisplacementEditField.Value;
app.occupancy=app.OccupancyEditField.Value;
app.charge=app.ChargeEditField.Value;
app.region=app.RegionEditField.Value;       

try
    app.ErrorCodesEditField.Value='Conversion Started';
    app.ErrorCodesEditField.BackgroundColor='y';
    if app.FileType==1
        try 
            atomdata=coordinateConvert(app.file,0);
            downloadmessage='Process one is complete. Continuing...';
            app.ErrorCodesEditField.BackgroundColor='y';
        catch 
            downloadmessage='Process one failed to complete';
            app.ErrorCodesEditField.BackgroundColor='r';
        end
        app.ErrorCodesEditField.Value=downloadmessage;
        UnitData=atomdata.AtomData;
        [numberAtoms,~]=size(UnitData);
        UnitForChange=zeros(size(numberAtoms,8));
        app.numat=numberAtoms;
        app.TimingData=zeros([1 app.numat]);
        n = app.numat;
        for i=1:numberAtoms
            app.TimingData(1,i)=i;
            ph.XData = [0 i/n i/n 0];
            th.String = sprintf('%.0f%%',round((i/n)*100));
            drawnow
            UnitForChange(i,1)=atomicNumber(UnitData{i,1});
            UnitForChange(i,2)=UnitData{i,6};
            UnitForChange(i,3)=UnitData{i,7};
            UnitForChange(i,4)=UnitData{i,8};
            UnitForChange(i,5)=app.rms;
            UnitForChange(i,6)=app.occupancy;
            UnitForChange(i,7)=app.region;
            UnitForChange(i,8)=app.charge;
        end
        
        data=UnitForChange;
    elseif app.FileType==2
        data=app.InputFile;
    end
    
    app.Data=data;
    %%
    [app.NumAtoms,NumCol]=size(data);
    
    xCoordinates=data(:,2);
    yCoordinates=data(:,3);
    zCoordinates=data(:,4);
    
    minx=min(xCoordinates);
    miny=min(yCoordinates);
    minz=min(zCoordinates);
    
    translata=data;
    
    for i=1:app.NumAtoms
        translata(i,2)=data(i,2)-minx;
        translata(i,3)=data(i,3)-miny;
        translata(i,4)=data(i,4)-minz;
    end
    
    app.Translata=translata;
    
    %%
    xCoordinates=translata(:,2);
    yCoordinates=translata(:,3);
    zCoordinates=translata(:,4);
    maxx=max(xCoordinates);
    maxy=max(yCoordinates);
    uniquez=sort(unique(zCoordinates));
    difz=uniquez(2,1)-uniquez(1,1);
    app.DifZ=difz;
    
    if app.dz==0
        thick=difz;
    else
        thick=app.dz;
    end
    
    if app.sizeX==0
        X=maxx;
    else
        X=app.sizeX;
    end
    
    if app.sizeY==0
        Y=maxy;
    else
        Y=app.sizeY;
    end
    
    topLine=[X Y thick 0 0 0 0 0];
    
    app.TopLine=topLine;
    %%
    
    OutputFile=zeros([app.NumAtoms+1 NumCol]);
    for i=1:app.NumAtoms+1
        if i==1
            OutputFile(i,:)=topLine;
        else
            OutputFile(i,:)=translata(i-1,:);
        end
    end
    
    app.PreparedData=OutputFile;
    
    [row,col]=size(OutputFile);
    
    for i=1:row
        for j=1:col
            strOut=OutputFile(i,j);
            FinalOutput(i,j)={sprintf('%.5f',strOut)}; %#ok<AGROW> 
        end
    end
    
    FinalOutput=append(FinalOutput,'00000');
    
    FinalOutput=cell2table(FinalOutput);
    app.Output=FinalOutput;
    
    app.UITable.Data=app.Output;
    app.ErrorCodesEditField.Value='Preview is now ready';
    app.ErrorCodesEditField.BackgroundColor='g';
catch
    app.ErrorCodesEditField.Value='Conversion has failed, check file format- must be CrystalMaker Coordinate File';
    app.ErrorCodesEditField.BackgroundColor='r';
end


        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
            try
                selected_dir = uigetdir();
                file_name=app.SaveFileEditField.Value;
                savefigas=append(selected_dir,'\',file_name,'.txt');
                writetable(app.Output, savefigas, 'Delimiter','\t','WriteVariableNames', false); 
                app.ErrorCodesEditField.Value='File saved';
                app.ErrorCodesEditField.BackgroundColor='g';
            catch
                app.ErrorCodesEditField.Value='File has not saved';
                app.ErrorCodesEditField.BackgroundColor='r';
            end
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            startupFcn(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SampleSpecificationsTab = uitab(app.TabGroup);
            app.SampleSpecificationsTab.Title = 'Sample Specifications';
            app.GridLayout = uigridlayout(app.SampleSpecificationsTab);
            app.Crystal2MULTEMLabel = uilabel(app.GridLayout);
            app.Crystal2MULTEMLabel.Text = 'Crystal2MULTEM';
            app.ErrorCodesEditField = uieditfield(app.GridLayout, 'text');
            app.ErrorCodesEditField.Editable = 'off';
            app.ErrorCodesLabel = uilabel(app.GridLayout);
            app.ErrorCodesLabel.Text = 'Error Codes:';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.FileNameEditField = uieditfield(app.GridLayout2, 'text');
            app.FileNameEditField.Editable = 'off';
            app.FileNameLabel = uilabel(app.GridLayout2);
            app.FileNameLabel.Text = {'File Name:'; ''};
            app.FileButton = uibutton(app.GridLayout2, 'push');
            app.FileButton.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.FileButton.Icon = 'fileim.jpg';
            app.FileButton.Text = '';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.SaveFileEditField = uieditfield(app.GridLayout3, 'text');
            app.SaveFileEditFieldLabel = uilabel(app.GridLayout3);
            app.SaveFileEditFieldLabel.Text = {' Save File:'; ''};
            app.SaveFileButton = uibutton(app.GridLayout3, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Icon = 'fileim.jpg';
            app.SaveFileButton.Text = '';
            app.CONVERTButton = uibutton(app.GridLayout, 'push');
            app.CONVERTButton.ButtonPushedFcn = createCallbackFcn(app, @CONVERTButtonPushed, true);
            app.CONVERTButton.Text = 'CONVERT';
            app.CLEARButton = uibutton(app.GridLayout, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.ParametersPanel = uipanel(app.GridLayout);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout4 = uigridlayout(app.ParametersPanel);
            app.SampleXDimensionAngEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SampleXDimensionAngEditField.Limits = [0 Inf];
            app.SampleXDimensionAngEditFieldLabel = uilabel(app.GridLayout4);
            app.SampleXDimensionAngEditFieldLabel.Text = 'Sample X-Dimension (Ang):';
            app.SampleYDimensionAngEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SampleYDimensionAngEditField.Limits = [0 Inf];
            app.SampleYDimensionAngEditFieldLabel = uilabel(app.GridLayout4);
            app.SampleYDimensionAngEditFieldLabel.Text = 'Sample Y-Dimension (Ang):';
            app.SliceThicknessAngEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SliceThicknessAngEditField.Limits = [0 Inf];
            app.SliceThicknessAngEditFieldLabel = uilabel(app.GridLayout4);
            app.SliceThicknessAngEditFieldLabel.Text = 'Slice Thickness (Ang):';
            app.RegionEditField = uieditfield(app.GridLayout4, 'numeric');
            app.RegionEditField.Limits = [0 Inf];
            app.RegionEditFieldLabel = uilabel(app.GridLayout4);
            app.RegionEditFieldLabel.Text = 'Region:';
            app.OccupancyEditField = uieditfield(app.GridLayout4, 'numeric');
            app.OccupancyEditField.Limits = [0 Inf];
            app.OccupancyEditField.Value = 1;
            app.OccupancyLabel = uilabel(app.GridLayout4);
            app.OccupancyLabel.Text = 'Occupancy:';
            app.RootMeanSquaredDisplacementEditField = uieditfield(app.GridLayout4, 'numeric');
            app.RootMeanSquaredDisplacementEditField.Limits = [0 Inf];
            app.RootMeanSquaredDisplacementEditField.Value = 0.085;
            app.RootMeanSquaredDisplacementLabel = uilabel(app.GridLayout4);
            app.RootMeanSquaredDisplacementLabel.Text = 'Root Mean Squared Displacement:';
            app.ChargeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ChargeEditField.Limits = [0 Inf];
            app.ChargeEditFieldLabel = uilabel(app.GridLayout4);
            app.ChargeEditFieldLabel.Text = 'Charge:';
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.UITable = uitable(app.PreviewTab);
            app.UITable.ColumnName = {'Atomic Number'; 'X'; 'Y'; 'Z'; 'RMS'; 'Occupancy'; 'Region'; 'Charge'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crystal2MULTEM_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end